classdef Formulas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        equationTab                    matlab.ui.container.Tab
        epsilonEditField               matlab.ui.control.EditField
        PhngtrnhLabel_5                matlab.ui.control.Label
        errorTextArea                  matlab.ui.control.TextArea
        ErrorTextAreaLabel             matlab.ui.control.Label
        equationButton                 matlab.ui.control.Button
        repeatEditField                matlab.ui.control.NumericEditField
        RepeatLabel                    matlab.ui.control.Label
        rootEditField                  matlab.ui.control.NumericEditField
        RootLabel                      matlab.ui.control.Label
        bEquation_EditField            matlab.ui.control.NumericEditField
        aEquation_EditField            matlab.ui.control.NumericEditField
        intervalLabel                  matlab.ui.control.Label
        equation_methodListBox         matlab.ui.control.ListBox
        MethodLabel                    matlab.ui.control.Label
        intervalEditField              matlab.ui.control.EditField
        funcEditField                  matlab.ui.control.EditField
        PhngtrnhLabel                  matlab.ui.control.Label
        equationUIAxes                 matlab.ui.control.UIAxes
        interpolationTab               matlab.ui.container.Tab
        interpolation_polynomialArea   matlab.ui.control.TextArea
        PhngtrnhLabel_4                matlab.ui.control.Label
        interpolation_rootField        matlab.ui.control.NumericEditField
        interpolation_x0EditField      matlab.ui.control.NumericEditField
        interpolationLabel             matlab.ui.control.Label
        interpolation_methodListBox    matlab.ui.control.ListBox
        MethodLabel_2                  matlab.ui.control.Label
        interpolation_YField           matlab.ui.control.EditField
        PhngtrnhLabel_3                matlab.ui.control.Label
        interpolation_XField           matlab.ui.control.EditField
        PhngtrnhLabel_2                matlab.ui.control.Label
        interpolationButton            matlab.ui.control.Button
        interpolationUIAxes            matlab.ui.control.UIAxes
        regressionTab                  matlab.ui.container.Tab
        YLabel                         matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        regression_polynomialArea      matlab.ui.control.TextArea
        PhngtrnhLabel_8                matlab.ui.control.Label
        regression_estimatedY          matlab.ui.control.NumericEditField
        regression_estimatedX          matlab.ui.control.NumericEditField
        regressionLabel                matlab.ui.control.Label
        regression_YField              matlab.ui.control.EditField
        PhngtrnhLabel_7                matlab.ui.control.Label
        regression_XField              matlab.ui.control.EditField
        PhngtrnhLabel_6                matlab.ui.control.Label
        regression_methodListBox       matlab.ui.control.ListBox
        MethodLabel_3                  matlab.ui.control.Label
        regressionButtonPushed         matlab.ui.control.Button
        regressionUIAxes               matlab.ui.control.UIAxes
        derivationTab                  matlab.ui.container.Tab
        orLabel                        matlab.ui.control.Label
        fxEditField                    matlab.ui.control.EditField
        NhphmsfxLabel                  matlab.ui.control.Label
        derivation_YField              matlab.ui.control.EditField
        PhngtrnhLabel_10               matlab.ui.control.Label
        derivation_XField              matlab.ui.control.EditField
        PhngtrnhLabel_9                matlab.ui.control.Label
        ketquaEditField                matlab.ui.control.NumericEditField
        KtquEditFieldLabel             matlab.ui.control.Label
        xiEditField                    matlab.ui.control.NumericEditField
        NhpgitrcntnhohmEditFieldLabel  matlab.ui.control.Label
        hEditField                     matlab.ui.control.EditField
        StephLabel                     matlab.ui.control.Label
        notification                   matlab.ui.control.Label
        saisoButtonGroup               matlab.ui.container.ButtonGroup
        Oh2Button                      matlab.ui.control.RadioButton
        OhButton                       matlab.ui.control.RadioButton
        derivationButtonPushed         matlab.ui.control.Button
        phuongphapButtonGroup          matlab.ui.container.ButtonGroup
        chieuluiButton                 matlab.ui.control.RadioButton
        trungtamButton                 matlab.ui.control.RadioButton
        chieutienButton                matlab.ui.control.RadioButton
        integrationTab                 matlab.ui.container.Tab
        integrationUIAxes              matlab.ui.control.UIAxes
        CreditsTab                     matlab.ui.container.Tab
        TasksArea                      matlab.ui.control.TextArea
        TasksLabel                     matlab.ui.control.Label
        NhmAnhLongLabel                matlab.ui.control.Label
        StudentIDLabel                 matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        StudentIDArea                  matlab.ui.control.TextArea
        MembersLabel                   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: equationButton
        function equationButtonPushed(app, event)
            method = app.equation_methodListBox.Value;
            fx = app.funcEditField.Value;
            a = app.aEquation_EditField.Value;
            b = app.bEquation_EditField.Value;
            e = str2double(app.epsilonEditField.Value);
            % Method
            if strcmp(method, 'Bisection')
                [x0, n, er] = bisection(fx, a, b, e);
            elseif strcmp(method, 'Interation')
                [x0, n, er] = interation(fx, a, b, e);
            elseif strcmp(method, 'Newton')
                [x0, n, er] = newtonEquation(fx, a, b, e);
            end
            % Print error
            app.errorTextArea.Value = er;
            % Plot the graph
            fx = str2func(['@(x)', fx]);
            t = a:(b-a)/100:b;
            y = arrayfun(fx, t);
            plot(app.equationUIAxes, t, y);
            app.rootEditField.Value = x0;
            app.repeatEditField.Value = n;
        end

        % Button pushed function: interpolationButton
        function interpolationButtonPushed(app, event)
            method = app.interpolation_methodListBox.Value;
            x = readArray(app.interpolation_XField.Value);
            y = readArray(app.interpolation_YField.Value);
            if isa(y, 'sym') == 1
                fx = matlabFunction(y);
                y = fx(x);
            end
            x0 = app.interpolation_x0EditField.Value;
            % Method
            if strcmp(method, 'Lagrange')
                [P, result] = lagrange(x, y, x0);
            elseif strcmp(method, 'Newton')
                [P, result] = newtonInterpolation(x, y, x0);
            end
            % Print result and polynomial
            P_str = string(P);
            app.interpolation_rootField.Value = result;
            app.interpolation_polynomialArea.Value = P_str;
            % Plot the graph
            left = x(1) - mod(x(1), 5);
            right = x(length(x));
            right = (floor(right/5) + 1) * 5;
            t = left : (right - left)/100 : right;
            y_P = arrayfun(matlabFunction(P), t);

            hold(app.interpolationUIAxes, "off");
            plot(app.interpolationUIAxes, t, y_P);
            hold(app.interpolationUIAxes, "on");
            plot(app.interpolationUIAxes, x, y, '*');
        end

        % Button pushed function: regressionButtonPushed
        function regressionButtonPushedPushed(app, event)
            method = app.regression_methodListBox.Value;
            digits(4);
            x1 = readArray(app.regression_XField.Value);
            y1 = readArray(app.regression_YField.Value);
            if isa(y1, 'sym') == 1
                fx = matlabFunction(y1);
                y1 = fx(x1);
            end
            syms x;
            % Method
            if strcmp(method, 'Linear')
                [a, b] = regressLinear(x1, y1);
                fx = vpa(a)*x + vpa(b);
            elseif strcmp(method, 'Exponential')
                [a, b] = regressExponential(x1, y1);
                fx = vpa(a) * x^vpa(b);
            elseif strcmp(method, 'Euler')
                [a, b] = regressEulerexp(x1, y1);
                fx = vpa(a) * exp(x*vpa(b));
            end
            y = arrayfun(matlabFunction(fx), x1);
            hold(app.regressionUIAxes, "off");
            plot(app.regressionUIAxes, x1, y1, "*r");
            hold(app.regressionUIAxes, "on");
            plot(app.regressionUIAxes, x1, y, "b");

            app.regression_polynomialArea.Value = string(fx);
            x0 = app.regression_estimatedX.Value;
            app.regression_estimatedY.Value = double(subs(fx, x, x0));
        end

        % Button pushed function: derivationButtonPushed
        function derivationButtonPushedPushed(app, event)
            h = str2double(app.hEditField.Value) ;            
            if isempty(app.derivation_XField.Value)
                x = 0 : h : h*20;
                fx = str2func(sprintf("%s", "@(x)", app.fxEditField.Value));
                y = feval(fx, x);
            else
                x = readArray(app.derivation_XField.Value);
                y = readArray(app.derivation_YField.Value);
                if isa(y, 'sym') == 1
                    fx = matlabFunction(y);
                    y = fx(x);
                end
            end
            tai_x = app.xiEditField.Value ;
            % Kiem tra phuong phap
            if app.chieutienButton.Value == 1
                phuongphap = 1;
            elseif app.trungtamButton.Value == 1
                phuongphap = 2;
            else
                phuongphap = 3;
            end
            % Kiem tra dieu kien sai so O(h)
            if app.OhButton.Value == 1
                saiso_oh = 1;
            else
                saiso_oh = 2;
            end
            % Print
            app.ketquaEditField.Value = derivation_Taylor(x, y, tai_x, phuongphap, saiso_oh);
            app.notification.FontColor = "Green";
            app.notification.Text = ("Successful!");
        end

        % Selection changed function: phuongphapButtonGroup
        function phuongphapButtonGroupSelectionChanged(app, event)
            if app.trungtamButton.Value == 1
                app.OhButton.Enable = 'off' ;
                app.saisoButtonGroup.SelectedObject = app.Oh2Button ;
            else
                app.OhButton.Enable = 'on' ;
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch (value)
                case 'Đặng Trung Kiên'
                    app.StudentIDArea.Value = '22207050';
                    app.TasksArea.Value = 'Lập trình và thiết kế giao diện tìm nghiệm';
                case 'Nguyễn Trung Kiên'
                    app.StudentIDArea.Value = '22207051';
                    app.TasksArea.Value = 'Lập trình và thiết kế giao diện nội suy';
                case 'Nguyễn Hà Tuấn Long'
                    app.StudentIDArea.Value = '22207057';
                    app.TasksArea.Value = 'Lập trình và thiết kế giao diện đạo hàm';
                case 'Bùi Phạm Thiên Phát'
                    app.StudentIDArea.Value = '22207067';
                    app.TasksArea.Value = 'Lập trình và thiết kế giao diện hồi quy';  
                case 'Nguyễn Đức Anh Tú'
                    app.StudentIDArea.Value = '22207095';
                    app.TasksArea.Value = 'Viết báo cáo Word';                    
                case 'Lê Hoàng Quốc Uy'
                    app.StudentIDArea.Value = '22207101';
                    app.TasksArea.Value = 'Lập trình và thiết kế giao diện vi phân';            
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.equationTab = uitab(app.TabGroup);
            app.equationTab.Title = 'Equation';
            app.equationUIAxes = uiaxes(app.equationTab);
            title(app.equationUIAxes, 'Graph')
            xlabel(app.equationUIAxes, 'x')
            ylabel(app.equationUIAxes, 'f(x)')
            zlabel(app.equationUIAxes, 'Z')
            app.PhngtrnhLabel = uilabel(app.equationTab);
            app.PhngtrnhLabel.Text = 'Function';
            app.funcEditField = uieditfield(app.equationTab, 'text');
            app.funcEditField.Placeholder = 'f(x)';
            app.intervalEditField = uieditfield(app.equationTab, 'text');
            app.MethodLabel = uilabel(app.equationTab);
            app.MethodLabel.Text = 'Method';
            app.equation_methodListBox = uilistbox(app.equationTab);
            app.equation_methodListBox.Items = {'Bisection', 'Interation', 'Newton'};
            app.equation_methodListBox.Value = 'Bisection';
            app.intervalLabel = uilabel(app.equationTab);
            app.intervalLabel.Text = 'Interval';
            app.aEquation_EditField = uieditfield(app.equationTab, 'numeric');
            app.aEquation_EditField.Placeholder = 'a';
            app.bEquation_EditField = uieditfield(app.equationTab, 'numeric');
            app.bEquation_EditField.Placeholder = 'b';
            app.RootLabel = uilabel(app.equationTab);
            app.RootLabel.Text = 'Solution';
            app.rootEditField = uieditfield(app.equationTab, 'numeric');
            app.rootEditField.Editable = 'off';
            app.RepeatLabel = uilabel(app.equationTab);
            app.RepeatLabel.Text = 'Repeat';
            app.repeatEditField = uieditfield(app.equationTab, 'numeric');
            app.repeatEditField.Editable = 'off';
            app.equationButton = uibutton(app.equationTab, 'push');
            app.equationButton.ButtonPushedFcn = createCallbackFcn(app, @equationButtonPushed, true);
            app.equationButton.Text = 'Run';
            app.ErrorTextAreaLabel = uilabel(app.equationTab);
            app.ErrorTextAreaLabel.Text = 'Error';
            app.errorTextArea = uitextarea(app.equationTab);
            app.errorTextArea.Editable = 'off';
            app.PhngtrnhLabel_5 = uilabel(app.equationTab);
            app.PhngtrnhLabel_5.Text = 'Error range';
            app.epsilonEditField = uieditfield(app.equationTab, 'text');
            app.epsilonEditField.Placeholder = '0.001';
            app.interpolationTab = uitab(app.TabGroup);
            app.interpolationTab.Title = 'Interpolation';
            app.interpolationUIAxes = uiaxes(app.interpolationTab);
            title(app.interpolationUIAxes, 'Graph')
            xlabel(app.interpolationUIAxes, 'x')
            ylabel(app.interpolationUIAxes, 'f(x)')
            zlabel(app.interpolationUIAxes, 'Z')
            app.interpolationButton = uibutton(app.interpolationTab, 'push');
            app.interpolationButton.ButtonPushedFcn = createCallbackFcn(app, @interpolationButtonPushed, true);
            app.interpolationButton.Text = 'Run';
            app.PhngtrnhLabel_2 = uilabel(app.interpolationTab);
            app.PhngtrnhLabel_2.Text = 'Array x';
            app.interpolation_XField = uieditfield(app.interpolationTab, 'text');
            app.interpolation_XField.Placeholder = '0, 0, 0, ...';
            app.PhngtrnhLabel_3 = uilabel(app.interpolationTab);
            app.PhngtrnhLabel_3.Text = 'Array y';
            app.interpolation_YField = uieditfield(app.interpolationTab, 'text');
            app.interpolation_YField.Placeholder = '0, 0, 0, ...';
            app.MethodLabel_2 = uilabel(app.interpolationTab);
            app.MethodLabel_2.Text = 'Method';
            app.interpolation_methodListBox = uilistbox(app.interpolationTab);
            app.interpolation_methodListBox.Items = {'Lagrange', 'Newton'};
            app.interpolation_methodListBox.Value = 'Lagrange';
            app.interpolationLabel = uilabel(app.interpolationTab);
            app.interpolationLabel.Text = 'Value to interpolate';
            app.interpolation_x0EditField = uieditfield(app.interpolationTab, 'numeric');
            app.interpolation_x0EditField.Placeholder = '0';
            app.interpolation_rootField = uieditfield(app.interpolationTab, 'numeric');
            app.interpolation_rootField.Editable = 'off';
            app.PhngtrnhLabel_4 = uilabel(app.interpolationTab);
            app.PhngtrnhLabel_4.Text = 'Interpolation polynomial';
            app.interpolation_polynomialArea = uitextarea(app.interpolationTab);
            app.interpolation_polynomialArea.Editable = 'off';
            app.regressionTab = uitab(app.TabGroup);
            app.regressionTab.Title = 'Regression';
            app.regressionUIAxes = uiaxes(app.regressionTab);
            title(app.regressionUIAxes, 'Graph')
            xlabel(app.regressionUIAxes, 'x')
            ylabel(app.regressionUIAxes, 'f(x)')
            zlabel(app.regressionUIAxes, 'Z')
            app.regressionButtonPushed = uibutton(app.regressionTab, 'push');
            app.regressionButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @regressionButtonPushedPushed, true);
            app.regressionButtonPushed.Text = 'Run';
            app.MethodLabel_3 = uilabel(app.regressionTab);
            app.MethodLabel_3.Text = 'Method';
            app.regression_methodListBox = uilistbox(app.regressionTab);
            app.regression_methodListBox.Items = {'Linear', 'Exponential', 'Euler'};
            app.regression_methodListBox.Value = 'Linear';
            app.PhngtrnhLabel_6 = uilabel(app.regressionTab);
            app.PhngtrnhLabel_6.Text = 'Array x';
            app.regression_XField = uieditfield(app.regressionTab, 'text');
            app.regression_XField.Placeholder = '0, 0, 0, ...';
            app.regression_XField.Value = '1, 2, 3, 4, 5';
            app.PhngtrnhLabel_7 = uilabel(app.regressionTab);
            app.PhngtrnhLabel_7.Text = 'Array y';
            app.regression_YField = uieditfield(app.regressionTab, 'text');
            app.regression_YField.Placeholder = '0, 0, 0, ...';
            app.regression_YField.Value = '0.5, 1.7, 3.4, 5.7, 8.4';
            app.regressionLabel = uilabel(app.regressionTab);
            app.regressionLabel.Text = 'Estimated regression';
            app.regression_estimatedX = uieditfield(app.regressionTab, 'numeric');
            app.regression_estimatedX.Placeholder = '0';
            app.regression_estimatedY = uieditfield(app.regressionTab, 'numeric');
            app.regression_estimatedY.Editable = 'off';
            app.PhngtrnhLabel_8 = uilabel(app.regressionTab);
            app.PhngtrnhLabel_8.Text = 'Interpolation polynomial';
            app.regression_polynomialArea = uitextarea(app.regressionTab);
            app.regression_polynomialArea.Editable = 'off';
            app.XLabel = uilabel(app.regressionTab);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.regressionTab);
            app.YLabel.Text = 'Y';
            app.derivationTab = uitab(app.TabGroup);
            app.derivationTab.Title = 'Derivation';
            app.phuongphapButtonGroup = uibuttongroup(app.derivationTab);
            app.phuongphapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @phuongphapButtonGroupSelectionChanged, true);
            app.phuongphapButtonGroup.Title = 'Chọn phương pháp đạo hàm';
            app.chieutienButton = uiradiobutton(app.phuongphapButtonGroup);
            app.chieutienButton.Text = 'Chiều tiến';
            app.chieutienButton.Value = true;
            app.trungtamButton = uiradiobutton(app.phuongphapButtonGroup);
            app.trungtamButton.Text = 'Trung tâm';
            app.chieuluiButton = uiradiobutton(app.phuongphapButtonGroup);
            app.chieuluiButton.Text = 'Chiều lùi';
            app.derivationButtonPushed = uibutton(app.derivationTab, 'push');
            app.derivationButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @derivationButtonPushedPushed, true);
            app.derivationButtonPushed.Text = 'Run';
            app.saisoButtonGroup = uibuttongroup(app.derivationTab);
            app.saisoButtonGroup.Title = 'Chọn giá trị sai số';
            app.OhButton = uiradiobutton(app.saisoButtonGroup);
            app.OhButton.Text = 'O(h)';
            app.OhButton.Value = true;
            app.Oh2Button = uiradiobutton(app.saisoButtonGroup);
            app.Oh2Button.Text = 'O(h^2)';
            app.notification = uilabel(app.derivationTab);
            app.notification.Text = '.';
            app.StephLabel = uilabel(app.derivationTab);
            app.StephLabel.Text = 'Step (h)';
            app.hEditField = uieditfield(app.derivationTab, 'text');
            app.hEditField.Value = '0.1';
            app.NhpgitrcntnhohmEditFieldLabel = uilabel(app.derivationTab);
            app.NhpgitrcntnhohmEditFieldLabel.Text = 'Nhập giá trị cần tính đạo hàm';
            app.xiEditField = uieditfield(app.derivationTab, 'numeric');
            app.xiEditField.Value = 0.5;
            app.KtquEditFieldLabel = uilabel(app.derivationTab);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.ketquaEditField = uieditfield(app.derivationTab, 'numeric');
            app.ketquaEditField.ValueDisplayFormat = '%11.5g';
            app.ketquaEditField.Editable = 'off';
            app.PhngtrnhLabel_9 = uilabel(app.derivationTab);
            app.PhngtrnhLabel_9.Text = 'Array x';
            app.derivation_XField = uieditfield(app.derivationTab, 'text');
            app.derivation_XField.Placeholder = '0, 0, 0, ...';
            app.PhngtrnhLabel_10 = uilabel(app.derivationTab);
            app.PhngtrnhLabel_10.Text = 'Array y';
            app.derivation_YField = uieditfield(app.derivationTab, 'text');
            app.derivation_YField.Placeholder = '0, 0, 0, ...';
            app.NhphmsfxLabel = uilabel(app.derivationTab);
            app.NhphmsfxLabel.Text = 'Function f(x)';
            app.fxEditField = uieditfield(app.derivationTab, 'text');
            app.fxEditField.Value = '- x.^2 + 1.8.*x + 2.5';
            app.orLabel = uilabel(app.derivationTab);
            app.orLabel.Text = 'or';
            app.integrationTab = uitab(app.TabGroup);
            app.integrationTab.Title = 'Integration';
            app.integrationUIAxes = uiaxes(app.integrationTab);
            title(app.integrationUIAxes, 'Đồ thị')
            xlabel(app.integrationUIAxes, 'x')
            ylabel(app.integrationUIAxes, 'f(x)')
            zlabel(app.integrationUIAxes, 'Z')
            app.CreditsTab = uitab(app.TabGroup);
            app.CreditsTab.Title = 'Credits';
            app.MembersLabel = uilabel(app.CreditsTab);
            app.MembersLabel.Text = 'Members';
            app.StudentIDArea = uitextarea(app.CreditsTab);
            app.StudentIDArea.Editable = 'off';
            app.StudentIDArea.Value = {'22207050'};
            app.DropDown = uidropdown(app.CreditsTab);
            app.DropDown.Items = {'Đặng Trung Kiên', 'Nguyễn Trung Kiên', 'Nguyễn Hà Tuấn Long', 'Bùi Phạm Thiên Phát', 'Nguyễn Đức Anh Tú', 'Lê Hoàng Quốc Uy'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Đặng Trung Kiên';
            app.StudentIDLabel = uilabel(app.CreditsTab);
            app.StudentIDLabel.Text = 'Student ID';
            app.NhmAnhLongLabel = uilabel(app.CreditsTab);
            app.NhmAnhLongLabel.Text = 'Nhóm: Anh Long';
            app.TasksLabel = uilabel(app.CreditsTab);
            app.TasksLabel.Text = 'Tasks';
            app.TasksArea = uitextarea(app.CreditsTab);
            app.TasksArea.Editable = 'off';
            app.TasksArea.Value = {'Lập trình và thiết kế giao diện tìm nghiệm'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Formulas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end